classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        CalculatePotentialTab        matlab.ui.container.Tab
        Element1DropDown             matlab.ui.control.DropDown
        Element1DropDownLabel        matlab.ui.control.Label
        WriteInputtoOutputButton     matlab.ui.control.Button
        OutputEditField              matlab.ui.control.NumericEditField
        OutputEditFieldLabel         matlab.ui.control.Label
        INPUTEditField               matlab.ui.control.NumericEditField
        INPUTEditFieldLabel          matlab.ui.control.Label
        TabGroup2                    matlab.ui.container.TabGroup
        LINEARTab                    matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        LOGTab                       matlab.ui.container.Tab
        UIAxes_2                     matlab.ui.control.UIAxes
        FitPotentialTab              matlab.ui.container.Tab
        SelectedPathEditField        matlab.ui.control.EditField
        SelectedPathEditFieldLabel   matlab.ui.control.Label
        SelectPathButton             matlab.ui.control.Button
        numberEditField              matlab.ui.control.NumericEditField
        numberEditFieldLabel         matlab.ui.control.Label
        WriteButton                  matlab.ui.control.Button
        DataSetNameEditField         matlab.ui.control.EditField
        DataSetNameEditFieldLabel    matlab.ui.control.Label
        DirectoryEditField           matlab.ui.control.EditField
        DirectoryEditFieldLabel      matlab.ui.control.Label
        matfileEditField             matlab.ui.control.EditField
        matfileEditFieldLabel        matlab.ui.control.Label
        CalculateScatteringIntegralsTab  matlab.ui.container.Tab
        CalcuateTransportCoefficientsTab  matlab.ui.container.Tab
        CalcuateDSMCCoefficientsTab  matlab.ui.container.Tab
    end

    
    properties (Access = public)
        matfile % Description
        directory
        DataSetName
        usrnumber
        selpath
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup.FontName="Courier";
            app.TabGroup.FontWeight = 'bold';
            app.TabGroup2.FontName="Courier";
            app.TabGroup2.FontWeight = 'bold';
        end

        % Callback function
        function LINEARButtonPushed(app, event)
            app.TabGroup2.SelectedTab = app.LINEARTab;
        end

        % Value changed function: matfileEditField
        function matfileEditFieldValueChanged(app, event)
            value = app.matfileEditField.Value;
            
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            app.matfile = app.matfileEditField.Value;
            app.directory = app.DirectoryEditField.Value;
            app.DataSetName = app.DataSetNameEditField.Value; 
            app.usrnumber = app.numberEditField.Value;
            edit /Users/JackG/Desktop/project/rgtfun/file.m
            FID=fopen('file.m','a');
            fprintf(FID, ' matfile = %s;\n',app.matfile);
            fprintf(FID, ' directory = %s;\n',app.directory);
            fprintf(FID, ' DataSetName = %s;\n',app.DataSetName);
            fprintf(FID, ' User_Number = %d;\n',app.usrnumber);
            fprintf(FID, ' FilePath = "%s";\n',app.selpath);
            fclose(FID);
            
        end

        % Button pushed function: SelectPathButton
        function SelectPathButtonPushed(app, event)
            app.selpath  = uigetdir;
            app.SelectedPathEditField.Value = app.selpath;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalculatePotentialTab = uitab(app.TabGroup);
            app.CalculatePotentialTab.Title = 'Calculate Potential';
            app.TabGroup2 = uitabgroup(app.CalculatePotentialTab);
            app.LINEARTab = uitab(app.TabGroup2);
            app.LINEARTab.Title = 'LINEAR';
            app.UIAxes = uiaxes(app.LINEARTab);
            title(app.UIAxes, 'Interatomic Potential')
            xlabel(app.UIAxes, 'Interatomic Distance (Å)')
            ylabel(app.UIAxes, 'Energy (eV)')
            zlabel(app.UIAxes, 'Z')
            app.LOGTab = uitab(app.TabGroup2);
            app.LOGTab.Title = 'LOG';
            app.UIAxes_2 = uiaxes(app.LOGTab);
            title(app.UIAxes_2, 'Interatomic Potential')
            xlabel(app.UIAxes_2, 'Interatomic Distance (Å)')
            ylabel(app.UIAxes_2, 'Energy (eV)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XScale = 'log';
            app.UIAxes_2.YScale = 'log';
            app.INPUTEditFieldLabel = uilabel(app.CalculatePotentialTab);
            app.INPUTEditFieldLabel.Text = 'INPUT';
            app.INPUTEditField = uieditfield(app.CalculatePotentialTab, 'numeric');
            app.OutputEditFieldLabel = uilabel(app.CalculatePotentialTab);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.CalculatePotentialTab, 'numeric');
            app.WriteInputtoOutputButton = uibutton(app.CalculatePotentialTab, 'push');
            app.WriteInputtoOutputButton.Text = 'Write Input to Output';
            app.Element1DropDownLabel = uilabel(app.CalculatePotentialTab);
            app.Element1DropDownLabel.Text = 'Element 1';
            app.Element1DropDown = uidropdown(app.CalculatePotentialTab);
            app.FitPotentialTab = uitab(app.TabGroup);
            app.FitPotentialTab.Title = 'Fit Potential';
            app.matfileEditFieldLabel = uilabel(app.FitPotentialTab);
            app.matfileEditFieldLabel.Text = 'matfile';
            app.matfileEditField = uieditfield(app.FitPotentialTab, 'text');
            app.matfileEditField.ValueChangedFcn = createCallbackFcn(app, @matfileEditFieldValueChanged, true);
            app.DirectoryEditFieldLabel = uilabel(app.FitPotentialTab);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.FitPotentialTab, 'text');
            app.DataSetNameEditFieldLabel = uilabel(app.FitPotentialTab);
            app.DataSetNameEditFieldLabel.Text = 'DataSetName';
            app.DataSetNameEditField = uieditfield(app.FitPotentialTab, 'text');
            app.WriteButton = uibutton(app.FitPotentialTab, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = 'Write';
            app.numberEditFieldLabel = uilabel(app.FitPotentialTab);
            app.numberEditFieldLabel.Text = 'number';
            app.numberEditField = uieditfield(app.FitPotentialTab, 'numeric');
            app.SelectPathButton = uibutton(app.FitPotentialTab, 'push');
            app.SelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButtonPushed, true);
            app.SelectPathButton.Text = 'SelectPath';
            app.SelectedPathEditFieldLabel = uilabel(app.FitPotentialTab);
            app.SelectedPathEditFieldLabel.Text = 'Selected Path';
            app.SelectedPathEditField = uieditfield(app.FitPotentialTab, 'text');
            app.CalculateScatteringIntegralsTab = uitab(app.TabGroup);
            app.CalculateScatteringIntegralsTab.Title = 'Calculate Scattering Integrals';
            app.CalcuateTransportCoefficientsTab = uitab(app.TabGroup);
            app.CalcuateTransportCoefficientsTab.Title = 'Calcuate Transport Coefficients';
            app.CalcuateDSMCCoefficientsTab = uitab(app.TabGroup);
            app.CalcuateDSMCCoefficientsTab.Title = 'Calcuate DSMC Coefficients';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end